classdef deshify < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        deshifyappUIFigure              matlab.ui.Figure
        DeshifyLabel                    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        Setimlook4dwindowTab            matlab.ui.container.Tab
        selectResult                    matlab.ui.control.HTML
        Selectimlook4dwindowButton      matlab.ui.control.Button
        HTML2_2                         matlab.ui.control.HTML
        ThicknessTab                    matlab.ui.container.Tab
        thicknessResult                 matlab.ui.control.HTML
        mmLabel                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        slicesLabel                     matlab.ui.control.Label
        ThicknessSlicesLabel            matlab.ui.control.Label
        RoiThicknessInMm                matlab.ui.control.NumericEditField
        ThresholdlevelLabel_2           matlab.ui.control.Label
        ShrinkThicknessButton           matlab.ui.control.Button
        HTML8_2                         matlab.ui.control.HTML
        HTML8                           matlab.ui.control.HTML
        TopHatTab                       matlab.ui.container.Tab
        cm3Label                        matlab.ui.control.Label
        VolumeGoalInCm3                 matlab.ui.control.NumericEditField
        Volumegoalcm3Label              matlab.ui.control.Label
        HTML3                           matlab.ui.control.HTML
        HTML2                           matlab.ui.control.HTML
        ShrinkButton                    matlab.ui.control.Button
        LateralVentricleTab             matlab.ui.container.Tab
        InflatewidthpixelsSpinner       matlab.ui.control.Spinner
        InflatewidthpixelsSpinnerLabel  matlab.ui.control.Label
        HTML2_3                         matlab.ui.control.HTML
        InflateButton                   matlab.ui.control.Button
        HTML_lat_vent_result            matlab.ui.control.HTML
        GlobalThresholdTab              matlab.ui.container.Tab
        HTML2_8                         matlab.ui.control.HTML
        PerformThresholding             matlab.ui.control.Button
        CalcThresholdButton             matlab.ui.control.Button
        HTML2_6                         matlab.ui.control.HTML
        HTML2_5                         matlab.ui.control.HTML
        thresholdLevel                  matlab.ui.control.NumericEditField
        ThresholdlevelLabel             matlab.ui.control.Label
        HTML2_4                         matlab.ui.control.HTML
        globalThresholdResult           matlab.ui.control.HTML
        ResultsTab                      matlab.ui.container.Tab
        deshResult                      matlab.ui.control.HTML
        scoreLabel                      matlab.ui.control.Label
        HTML2_7                         matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShrinkButton
        function top_hat_shrink(app, event)

            % Set value from textarea
            value = app.VolumeGoalInCm3.Value;
            evalin('base', ['VolumeGoalInCm3 = ' num2str(value) ]);

            % Run scripts
            evalin('base', 'ROI_data_to_workspace' );
            evalin('base', 'top_hat_shrink');
            if ( evalin('base', 'returnCode' ) == 0)
                app.HTML3.HTMLSource = 'Select next tab';
            else
                app.HTML3.HTMLSource = 'FAIL, check MATLAB command window';
            end
            
            % Switch to "Lat Vent" ROI
            SelectROI(5 );
            SelectOrientation('Cor');
        end

        % Button pushed function: InflateButton
        function inflate(app, event)
            evalin('base', 'ROI_data_to_workspace' );
            disp(['dr=' app.InflatewidthpixelsSpinner.Value ]);
            evalin('base', ['deshify_dr = ' num2str(app.InflatewidthpixelsSpinner.Value) ]); % Override dr in script
            evalin('base', 'inflate_VOI');
            if ( evalin('base', 'returnCode' ) == 0)
                app.HTML_lat_vent_result.HTMLSource = 'Select next tab';
            else
                app.HTML_lat_vent_result.HTMLSource = 'FAIL, check MATLAB command window';
            end
                
        end

        % Button pushed function: Selectimlook4dwindowButton
        function selectImlook4dWindow(app, event)
            evalin('base', 'imlook4d_current_handle = gcf' );
            evalin('base', 'ROI_data_to_workspace' );
            
            % Switch to "Top Hat" ROI
            SelectOrientation('Cor');
            SelectROI(3 );
            
            app.selectResult.HTMLSource = 'Select next tab';
            
        end

        % Button pushed function: CalcThresholdButton
        function calcGlobalThreshold(app, event)
            evalin('base', 'ROI_data_to_workspace' );
            
            % Merge VOIs (except for ROI 4 'Flood-fill block' )
            evalin('base', ...
                'deshify_data0 = [ imlook4d_ROI_data.pixels{ 1}; imlook4d_ROI_data.pixels{ 2}; imlook4d_ROI_data.pixels{ 3}; imlook4d_ROI_data.pixels{ 5} ];' );
            
            % Calculate threshold value
            evalin('base', 'csf_threshold2_back' );
            app.thresholdLevel.Value = evalin('base', 'round(threshold)');
                     
            if ( evalin('base', 'returnCode' ) == 0)
                app.HTML2_8.HTMLSource = 'SUCCESS, inspect the histogram';
            else
                app.HTML2_8.HTMLSource = 'FAIL, check MATLAB command window';
            end
            
                       
        end

        % Button pushed function: PerformThresholding
        function performGlobalThresholding(app, event)
            %evalin('base', 'ROI_data_to_workspace' );
            
            % Close histogram
            try
                evalin('base', 'close(histWindow)'); 
            catch ME
            end
            
            % Perform thresholding
            evalin('base', [ 'threshold=' num2str(app.thresholdLevel.Value) ] ); % Read threshold from input field
            evalin('base', 'threshold_all' );    
            evalin('base', 'ROI_data_to_workspace' );
                                 
            if ( evalin('base', 'returnCode' ) == 0)
                app.globalThresholdResult.HTMLSource = 'Select next tab. <br> (ROI values are in clipboard if you are curious)';
            else
                app.globalThresholdResult.HTMLSource = 'FAIL, check MATLAB command window';
            end
            
        end

        % Button down function: Setimlook4dwindowTab
        function firstTabClicked(app, event)
            app.HTML_lat_vent_result.HTMLSource = 'Wait for result ...';
            app.globalThresholdResult.HTMLSource = 'Wait for result ...';
            app.selectResult.HTMLSource =  'Wait for result ...';
            app.HTML_lat_vent_result.HTMLSource =  'Wait for result ...';
            app.HTML2_8.HTMLSource =  'Wait for result ...';
            app.HTML3.HTMLSource = 'Wait for result ...';
        end

        % Button down function: ResultsTab
        function resultsTabEntered(app, event)

            evalin('base', 'calc_desh_score' );    
            
                                 
            if ( evalin('base', 'returnCode' ) == 0)
                app.deshResult.HTMLSource = 'qDESH value is in clipboard <br>(ready to paste into Excel etc)';
                app.scoreLabel.Text   = num2str( evalin('base', 'desh_value') );
                clipboard('copy', app.scoreLabel.Text);
            else
                app.deshResult.HTMLSource = 'FAIL, check MATLAB command window';
                app.scoreLabel.Text  = num2str( evalin('base', 'desh_value') );
            end
        end

        % Button pushed function: ShrinkThicknessButton
        function ShrinkThicknessButtonCallback(app, event)


            % Run script
            numberOfSlicesText = app.ThicknessSlicesLabel.Text
            evalin('base', [ 'Fixate_number_of_slices(' numberOfSlicesText ')' ] );

            if ( evalin('base', 'returnCode' ) == 0)
                app.thicknessResult.HTMLSource = 'Select next tab';
                SelectOrientation('Sag');
            else
                app.thicknessResult.HTMLSource = 'FAIL, verify that the 3 first ROIs have same last slice!';
            end

        end

        % Value changed function: RoiThicknessInMm
        function RoiThicknessChanged(app, event)
            value = app.RoiThicknessInMm.Value;

            % Calculate number of slices
            thicknessInMm = app.RoiThicknessInMm.Value;
            sliceThickness = evalin('base','imlook4d_current_handles.image.sliceSpacing');
            numberOfSlices = round( thicknessInMm / sliceThickness);

            % Display number of slices
            app.ThicknessSlicesLabel.Text = num2str(numberOfSlices)
            
        end

        % Button down function: ThicknessTab
        function thicknessTabClicked(app, event)
            RoiThicknessChanged(app, [])
        end

        % Value changed function: thresholdLevel
        function thresholdLevelValueChanged(app, event)
            % Move threshold-line in histogram
            th = num2str( app.thresholdLevel.Value ); % Text input
            evalin('base','lineHandle = histWindow.findobj(''Tag'', ''thresholdline''); '); % Get line handle
            evalin('base',[ 'lineHandle.XData = [ ' th ' ' th  ' ]; ' ]); % Set new line x-position
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.deshifyappUIFigure = uifigure('Visible', 'off');
            app.deshifyappUIFigure.Name = 'deshify app';
            app.TabGroup = uitabgroup(app.deshifyappUIFigure);
            app.Setimlook4dwindowTab = uitab(app.TabGroup);
            app.Setimlook4dwindowTab.Title = '0) Set imlook4d window';
            app.Setimlook4dwindowTab.ButtonDownFcn = createCallbackFcn(app, @firstTabClicked, true);
            app.HTML2_2 = uihtml(app.Setimlook4dwindowTab);
            app.HTML2_2.HTMLSource = '<b>Instruction : </b><br><br>1) Open a baseline-corrected T1 volume in ACPC space with imlook4d  <br>2) Draw the following VOIs according to separate instructions <br> <ol> <li> Sylvian sin</li> <li> Sylvian dx</li> <li> Top hat</li> <li>Flood-fill block</li> <li> Lat vent </li></ol>3) Make imlook4d window top most window<br>  4) Click "Select imlook4d window" ';
            app.Selectimlook4dwindowButton = uibutton(app.Setimlook4dwindowTab, 'push');
            app.Selectimlook4dwindowButton.ButtonPushedFcn = createCallbackFcn(app, @selectImlook4dWindow, true);
            app.Selectimlook4dwindowButton.Text = 'Select imlook4d window';
            app.selectResult = uihtml(app.Setimlook4dwindowTab);
            app.selectResult.HTMLSource = 'Wait for result ...';
            app.ThicknessTab = uitab(app.TabGroup);
            app.ThicknessTab.Title = '1) Thickness';
            app.ThicknessTab.ButtonDownFcn = createCallbackFcn(app, @thicknessTabClicked, true);
            app.HTML8 = uihtml(app.ThicknessTab);
            app.HTML8.HTMLSource = '<b>Instruction : </b><br><br> 1) Make sure 3 first ROIs end in same posterior slice <br> 2) Edit thickness ';
            app.HTML8_2 = uihtml(app.ThicknessTab);
            app.HTML8_2.HTMLSource = '3) Press "Shrink" button';
            app.ShrinkThicknessButton = uibutton(app.ThicknessTab, 'push');
            app.ShrinkThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @ShrinkThicknessButtonCallback, true);
            app.ShrinkThicknessButton.Text = 'Shrink';
            app.ThresholdlevelLabel_2 = uilabel(app.ThicknessTab);
            app.ThresholdlevelLabel_2.Text = 'Thickness ';
            app.RoiThicknessInMm = uieditfield(app.ThicknessTab, 'numeric');
            app.RoiThicknessInMm.ValueChangedFcn = createCallbackFcn(app, @RoiThicknessChanged, true);
            app.RoiThicknessInMm.Value = 20;
            app.ThicknessSlicesLabel = uilabel(app.ThicknessTab);
            app.ThicknessSlicesLabel.Text = 'xx';
            app.slicesLabel = uilabel(app.ThicknessTab);
            app.slicesLabel.Text = 'slices )';
            app.Label = uilabel(app.ThicknessTab);
            app.Label.Text = '( ';
            app.mmLabel = uilabel(app.ThicknessTab);
            app.mmLabel.Text = 'mm';
            app.thicknessResult = uihtml(app.ThicknessTab);
            app.thicknessResult.HTMLSource = 'Wait for result ...';
            app.TopHatTab = uitab(app.TabGroup);
            app.TopHatTab.Title = '2) Top Hat';
            app.ShrinkButton = uibutton(app.TopHatTab, 'push');
            app.ShrinkButton.ButtonPushedFcn = createCallbackFcn(app, @top_hat_shrink, true);
            app.ShrinkButton.Text = 'Shrink';
            app.HTML2 = uihtml(app.TopHatTab);
            app.HTML2.HTMLSource = '<b>Instruction : </b><br><br>1) Make sure VOI is "Top Hat"  <br>2) Press "Shrink" button';
            app.HTML3 = uihtml(app.TopHatTab);
            app.HTML3.HTMLSource = 'Wait for result ...';
            app.Volumegoalcm3Label = uilabel(app.TopHatTab);
            app.Volumegoalcm3Label.Text = 'Volume goal ';
            app.VolumeGoalInCm3 = uieditfield(app.TopHatTab, 'numeric');
            app.VolumeGoalInCm3.Value = 30;
            app.cm3Label = uilabel(app.TopHatTab);
            app.cm3Label.Text = 'cm3';
            app.LateralVentricleTab = uitab(app.TabGroup);
            app.LateralVentricleTab.Title = '3) Lateral Ventricle';
            app.HTML_lat_vent_result = uihtml(app.LateralVentricleTab);
            app.HTML_lat_vent_result.HTMLSource = 'Wait for result ...';
            app.InflateButton = uibutton(app.LateralVentricleTab, 'push');
            app.InflateButton.ButtonPushedFcn = createCallbackFcn(app, @inflate, true);
            app.InflateButton.Text = 'Inflate';
            app.HTML2_3 = uihtml(app.LateralVentricleTab);
            app.HTML2_3.HTMLSource = '<b>Instruction : </b><br><br>1) Make sure VOI is "Lat Vent"  <br>2) Press "Inflate" button';
            app.InflatewidthpixelsSpinnerLabel = uilabel(app.LateralVentricleTab);
            app.InflatewidthpixelsSpinnerLabel.Text = 'Inflate width (pixels)';
            app.InflatewidthpixelsSpinner = uispinner(app.LateralVentricleTab);
            app.InflatewidthpixelsSpinner.Limits = [1 Inf];
            app.InflatewidthpixelsSpinner.Value = 7;
            app.GlobalThresholdTab = uitab(app.TabGroup);
            app.GlobalThresholdTab.Title = '4) Global Threshold';
            app.globalThresholdResult = uihtml(app.GlobalThresholdTab);
            app.globalThresholdResult.HTMLSource = 'Wait for result ...';
            app.HTML2_4 = uihtml(app.GlobalThresholdTab);
            app.HTML2_4.HTMLSource = '<b>Instruction : </b><br><br>1) Click "Calculate Global Threshold" button  ';
            app.ThresholdlevelLabel = uilabel(app.GlobalThresholdTab);
            app.ThresholdlevelLabel.Text = 'Threshold level';
            app.thresholdLevel = uieditfield(app.GlobalThresholdTab, 'numeric');
            app.thresholdLevel.ValueDisplayFormat = '%.0f';
            app.thresholdLevel.ValueChangedFcn = createCallbackFcn(app, @thresholdLevelValueChanged, true);
            app.HTML2_5 = uihtml(app.GlobalThresholdTab);
            app.HTML2_5.HTMLSource = '3) Click "Perform Thresholding" button for all VOIs';
            app.HTML2_6 = uihtml(app.GlobalThresholdTab);
            app.HTML2_6.HTMLSource = '2) Inspect histogram, and adjust "Threshold level" manually if needed.  Green line indicates threshold ';
            app.CalcThresholdButton = uibutton(app.GlobalThresholdTab, 'push');
            app.CalcThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @calcGlobalThreshold, true);
            app.CalcThresholdButton.Text = 'Calculate Global Threshold';
            app.PerformThresholding = uibutton(app.GlobalThresholdTab, 'push');
            app.PerformThresholding.ButtonPushedFcn = createCallbackFcn(app, @performGlobalThresholding, true);
            app.PerformThresholding.Text = 'Perform Thresholding';
            app.HTML2_8 = uihtml(app.GlobalThresholdTab);
            app.HTML2_8.HTMLSource = 'Wait for result ...';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTab.ButtonDownFcn = createCallbackFcn(app, @resultsTabEntered, true);
            app.HTML2_7 = uihtml(app.ResultsTab);
            app.HTML2_7.HTMLSource = '<b>Results : </b><br><br>qDESH score:';
            app.scoreLabel = uilabel(app.ResultsTab);
            app.scoreLabel.Tag = 'desh_score_text';
            app.scoreLabel.Text = 'score';
            app.deshResult = uihtml(app.ResultsTab);
            app.deshResult.HTMLSource = 'Wait for result ...';
            app.Image = uiimage(app.deshifyappUIFigure);
            app.Image.ImageSource = 'desh_image.png';
            app.DeshifyLabel = uilabel(app.deshifyappUIFigure);
            app.DeshifyLabel.Text = 'Deshify';
            % Show the figure after all components are created
            app.deshifyappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deshify

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.deshifyappUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.deshifyappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.deshifyappUIFigure)
        end
    end
end